classdef paramAdjustApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ResetButton                matlab.ui.control.Button
        ShiftCheckBox              matlab.ui.control.CheckBox
        WidthCheckBox              matlab.ui.control.CheckBox
        PlateauCheckBox            matlab.ui.control.CheckBox
        SkewCheckBox               matlab.ui.control.CheckBox
        TiltCheckBox               matlab.ui.control.CheckBox
        SliderLabel                matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        SlidervalueEditFieldLabel  matlab.ui.control.Label
        SlidervalueEditField       matlab.ui.control.NumericEditField
        SettouseButton             matlab.ui.control.Button
        Skew_gainEditFieldLabel    matlab.ui.control.Label
        Skew_gainEditField         matlab.ui.control.NumericEditField
        invertgraphCheckBox        matlab.ui.control.CheckBox
        RightPanel                 matlab.ui.container.Panel
        maxxEditFieldLabel         matlab.ui.control.Label
        maxxEditField              matlab.ui.control.NumericEditField
        coef_X0EditFieldLabel      matlab.ui.control.Label
        coef_X0EditField           matlab.ui.control.NumericEditField
        coef_X1EditFieldLabel      matlab.ui.control.Label
        coef_X1EditField           matlab.ui.control.NumericEditField
        coef_X2EditFieldLabel      matlab.ui.control.Label
        coef_X2EditField           matlab.ui.control.NumericEditField
        coef_X3EditFieldLabel      matlab.ui.control.Label
        coef_X3EditField           matlab.ui.control.NumericEditField
        MeasurementsPanel          matlab.ui.container.Panel
        MaxYEditFieldLabel         matlab.ui.control.Label
        MaxYEditField              matlab.ui.control.NumericEditField
        MinYEditFieldLabel         matlab.ui.control.Label
        MinYEditField              matlab.ui.control.NumericEditField
        TraceXEditFieldLabel       matlab.ui.control.Label
        TraceXEditField            matlab.ui.control.NumericEditField
        TraceYEditFieldLabel       matlab.ui.control.Label
        TraceYEditField            matlab.ui.control.NumericEditField
        LButton                    matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        MaxY_XLabel                matlab.ui.control.Label
        MaxY_XEditField            matlab.ui.control.NumericEditField
        MinY_XLabel                matlab.ui.control.Label
        MinY_XEditField            matlab.ui.control.NumericEditField
        coef_X4EditFieldLabel      matlab.ui.control.Label
        coef_X4EditField           matlab.ui.control.NumericEditField
        ExportButton               matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        coeffx % Coeff used in measurements, to be exported
        xx % xmax/range in cm
        traceX % x value for the trace window
        paramtype % 1=Bl 2=Kd 3=Ld
        orig_xmax % original range used in plots
        orig_coeffx % original coeff when app started
        adjChoice % current selection of adjustment method
        temp_coeffx % coeff used in trial plots, not yet used
        skew_gain % size of the bump to be added
        invert_graph % invert the y axis of temp plot
        graphTitle % Description
        graphYlabel % Description
    end
    
    properties (Access = public)
        xmax % Description
    end
    
    methods (Access = private)
        
        function results = updateFields(app)
            myxx = app.xmax;            
            app.maxxEditField.Value = myxx;
                       
            % update display of coef
            app.coef_X0EditField.Value = app.coeffx(5);
            app.coef_X1EditField.Value = app.coeffx(4);
            app.coef_X2EditField.Value = app.coeffx(3);
            app.coef_X3EditField.Value = app.coeffx(2);
            app.coef_X4EditField.Value = app.coeffx(1);
            
            % put values into ymax, ymin, xx
            mycoeff = app.coeffx;
            myxx = myxx/100;
            xxx = -myxx: myxx/10: myxx;
            app.xx = xxx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            [maxy, mxidx] = max(yyy);
            [miny, mnidx] = min(yyy);
            app.MaxYEditField.Value = maxy;
            app.MaxY_XEditField.Value = xcm(mxidx);
            app.MinYEditField.Value = miny;
            app.MinY_XEditField.Value = xcm(mnidx);
            
            % put values into traceX
            app.traceX = 0;
            app.TraceYEditField.Value = polyval(mycoeff, 0);
            
            % plot graph 
            cla(app.UIAxes);
            
            hold(app.UIAxes,'off')
            line(app.UIAxes, xcm, yyy);
            title(app.UIAxes, app.graphTitle)
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes, 'position (cm)');
            ylabel(app.UIAxes, app.graphYlabel );
            results = 1;
        end
        
        function results = updateChoice(app)
            app.ShiftCheckBox.Value = 0;
            app.WidthCheckBox.Value = 0;
            app.PlateauCheckBox.Value = 0;
            app.SkewCheckBox.Value = 0;
            app.TiltCheckBox.Value = 0;
            switch(app.adjChoice)
                case 'shift'
                    app.Slider.Limits = [-0.5 0.5]; 
                    app.ShiftCheckBox.Value = 1;
                case 'width'
                    app.Slider.Limits = [-0.9 0.9]; 
                    app.WidthCheckBox.Value = 1;
                case 'plateau'
                    app.Slider.Limits = [-1 1]; 
                    app.PlateauCheckBox.Value = 1;
                case 'skew'
                    app.Slider.Limits = [-app.xmax app.xmax]; 
                    app.SkewCheckBox.Value = 1;
                case 'tilt'
                    app.Slider.Limits = [-0.3 0.3]; 
                    app.TiltCheckBox.Value = 1;
                otherwise
                    app.adjChoice = 'shift';
                    app.ShiftCheckBox.Value = 1;
                    
            end
            results = 1;
        end
        
        function results = choice_shift(app, newVal)
            Blxx = app.coeffx;
            ss = newVal/100;
            sp=[Blxx(1) (Blxx(2)-Blxx(1)*4*ss) (Blxx(1)*6*ss^2-Blxx(2)*3*ss+Blxx(3)) (Blxx(2)*3*ss^2-4*Blxx(1)*ss^3-2*Blxx(3)*ss+Blxx(4)) (Blxx(1)*ss^4- Blxx(2)*ss^3+ Blxx(3)*ss^2 -Blxx(4)*ss + Blxx(5))];
            app.temp_coeffx = sp;
            results = 1;
        end
        
        function results = choice_width(app, newVal)
            gg = newVal+1;
            Blxx = app.coeffx;
            sp= gg*Blxx;
            app.temp_coeffx = sp;
            results = 1;
        end
        
        function results = choice_plateau(app, newVal)
            g = newVal+1;
            app.temp_coeffx = app.coeffx;
            app.temp_coeffx(5) = app.coeffx(5)*g;
            results = 1;
        end
        
        function results = choice_skew(app, newVal)
            newin = newVal/100;
            x = app.xx;
            ll = length(x);
            pt = 1;
            for ii=1:ll
                if(x(ii)> newin)
                    pt = ii;
                    break;
                end
            end

            blen = round(ll/4); % 15, span=15
            if mod(blen,2)==0
                blen = blen+1;
            end
            hblen = floor(blen/2);
            
            % loation to apply bell shape mid point
            %pt = round(ll/2+ll/4);  % range [-xmax xmax]
            %pt = round(ll/4);
            
            % create bell shape window
            bx = linspace(-3,3,blen);
            %bx = [-3:.1:3]; %
            by = normpdf(bx,0,1);
            gain = app.skew_gain;
            g = by*gain+1;                  %gain
            
            % find index to apply bell shape
            lidx = pt- hblen;
            if lidx<1
                g(1:abs(lidx)+1)=[];
                lidx = 1;
            end
            ridx = pt+hblen;
            if ridx > ll
                dd = ll-ridx;
                g(blen-dd+1:blen)=[];            
                ridx = ll;
            end
            
            % get target response
            targetyy = polyval(app.coeffx, x);
            targetyy(lidx:ridx) = targetyy(lidx:ridx) +g;
            
            [sp] = polyfit(x, targetyy, 4);
            app.temp_coeffx = sp;

            results = 1;
        end
        
        function results = choice_tilt(app, gg)
            x = app.xx;
            Blxx = app.coeffx;
            Byy = polyval(app.coeffx, x);            
            [mxval, mxidx] = max(Byy);
            [mnval, mnidx] = max(Byy);
            ll = length(Byy);
            cnvx = 0;
            myidx = mnidx;
            if mxidx>ll/3 && mxidx<ll/3*2
                cnvx = 1; % convex graph ^
                myidx = mxidx;
            end
            pt = x(myidx);
            
            targetByy = Byy;
            if( gg > 0)
                myxx = x;
                myxx(myidx:end) = x(myidx:end)*(1-gg);
            end
            if( gg < 0)
                myxx = x;
                myxx(1:myidx) = x(1:myidx)*(1-abs(gg));
            end
            targetyy = polyval(Blxx, myxx);
            [sp] = polyfit(x, targetyy, 4);
            app.temp_coeffx = sp;
            
            results = 1;
        end
        
        function results = temp_updateFields(app)
            myxx = app.xmax;            
            %app.maxxEditField.Value = xx;
                                   
            % put values into ymax, ymin, xx
            mycoeff = app.temp_coeffx;
            myxx = myxx/100;
            xxx = -myxx: myxx/10: myxx;
            app.xx = xxx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            [maxy, mxidx] = max(yyy);
            [miny, mnidx] = min(yyy);
            
            
            % plot graph 
%            app.UIAxes.cla;
            if app.invert_graph==1
                temp = 1./yyy;
                hold(app.UIAxes,'off')
                line(app.UIAxes, xcm, temp, 'Color', 'green');
                title(app.UIAxes, 'inverted')
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'position (cm)');
                ylabel(app.UIAxes, ' ' );
            else
                hold(app.UIAxes,'off')
                line(app.UIAxes, xcm, yyy, 'Color', 'green');
                title(app.UIAxes, app.graphTitle)
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'position (cm)');
                ylabel(app.UIAxes, app.graphYlabel );
            end
            results = 1;          
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, xx, coeff, paramtype, strTitle, strylabel)
            disp('got data')
            app.paramtype = paramtype;
            
            % put value into xmax
            app.xmax = xx*100;
            app.orig_xmax = xx*100;
            app.maxxEditField.Value = xx*100;
            
            % put values into coef
            app.coeffx = zeros(1,5);
            app.coef_X0EditField.Value = coeff(1);
            app.coeffx(5) = coeff(1);
            app.coef_X1EditField.Value = coeff(2);
            app.coeffx(4) = coeff(2);
            app.coef_X2EditField.Value = coeff(3);
            app.coeffx(3) = coeff(3);
            app.coef_X3EditField.Value = coeff(4);
            app.coeffx(2) = coeff(4);
            app.coef_X4EditField.Value = coeff(5);
            app.coeffx(1) = coeff(5);
            app.orig_coeffx = app.coeffx;
            app.temp_coeffx = app.coeffx;
            
            % put values into ymax, ymin, xx
            mycoeff = app.coeffx;
            xxx = -xx: xx/10: xx;
            app.xx = xxx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            [maxy, mxidx] = max(yyy);
            [miny, mnidx] = min(yyy);
            app.MaxYEditField.Value = maxy;
            app.MaxY_XEditField.Value = xcm(mxidx);
            app.MinYEditField.Value = miny;
            app.MinY_XEditField.Value = xcm(mnidx);
            
            % put values into traceX
            app.traceX = 0;
            app.TraceYEditField.Value = polyval(mycoeff, 0);
            
            % remember graph title and ylabel
            app.graphYlabel = strylabel;
            app.graphTitle = strTitle;
            
            % plot graph            
            line(app.UIAxes, xcm, yyy);
            title(app.UIAxes, strTitle)
            grid(app.UIAxes, 'on')
            xlabel(app.UIAxes, 'position (cm)');
            ylabel(app.UIAxes, strylabel );
          
            % default choice is 1 = shift
            app.adjChoice = 'shift';
            app.ShiftCheckBox.Value = 1;
            
            % default skew gain
            if paramtype == 1
                app.skew_gain = 8;
                app.Skew_gainEditField.Value = 8;
            else
                app.skew_gain = -8;
                app.Skew_gainEditField.Value = -8;                
            end
            
            % slider init
            app.Slider.Limits = [-0.5 0.5]; 
            
            % default invert to zero
            app.invert_graph = 0;
        end

        % Value changed function: ShiftCheckBox
        function ShiftCheckBoxValueChanged(app, event)
            value = app.ShiftCheckBox.Value;
            if value == true
                app.adjChoice = 'shift';
                result = updateChoice(app);
            else
                app.ShiftCheckBox.Value = 1;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % put value into xmax
            myxx = app.orig_xmax;
            app.xmax = myxx;
            
            % put values into coef
            app.coeffx = app.orig_coeffx;
            
            app.adjChoice = 'shift';
            
            % reset invert button
            app.invert_graph = 0;
            app.invertgraphCheckBox.Value = 0;
            
            updateFields(app);
        end

        % Value changed function: WidthCheckBox
        function WidthCheckBoxValueChanged(app, event)
            value = app.WidthCheckBox.Value;
            if value == true
                app.adjChoice = 'width';
                updateChoice(app);
            else
                app.WidthCheckBox.Value = 1;
            end            
        end

        % Value changed function: PlateauCheckBox
        function PlateauCheckBoxValueChanged(app, event)
            value = app.PlateauCheckBox.Value;
            if value == true
                app.adjChoice = 'plateau';
                updateChoice(app);
            else
                app.PlateauCheckBox.Value = 1;
            end            
        end

        % Value changed function: SkewCheckBox
        function SkewCheckBoxValueChanged(app, event)
            value = app.SkewCheckBox.Value;
            if value == true
                app.adjChoice = 'skew';
                updateChoice(app);
            else
                app.SkewCheckBox.Value = 1;
            end            
        end

        % Value changed function: TiltCheckBox
        function TiltCheckBoxValueChanged(app, event)
            value = app.TiltCheckBox.Value;
            if value == true
                app.adjChoice = 'tilt';
                updateChoice(app);
            else
                app.TiltCheckBox.Value = 1;
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            switch(app.adjChoice)
                case 'shift'
                    result = choice_shift(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end
                    app.Slider.Value = 0;
                    app.SlidervalueEditField.Value = 0;
                case 'width'
                    result = choice_width(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end
                    app.Slider.Value = 0;
                    app.SlidervalueEditField.Value = 0;
                case 'plateau'
                    result = choice_plateau(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end
                    app.Slider.Value = 0;
                    app.SlidervalueEditField.Value = 0;                    
                case 'skew'
                    result = choice_skew(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end
                    app.Slider.Value = 0;
                    app.SlidervalueEditField.Value = 0;                    
                case 'tilt'
                    result = choice_tilt(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end
                    app.Slider.Value = 0;
                    app.SlidervalueEditField.Value = 0;                    
                otherwise
                    app.adjChoice = 'shift';
                    app.ShiftCheckBox.Value = 1;
                    
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            assignin("base",'xmax',app.xmax/100);
            lstate = evalin('base', 'state');
            
            if app.paramtype == 1
                % [Blxx] = [b8 b7 b6 b5 b4 b3 b2 b1 b0];
                Blxx = fliplr(app.coeffx);
                assignin("base",'Blxx', Blxx);
                lstate.elec.Bl = Blxx(1);
            end
            if app.paramtype == 2
                % [Kdxx] = [0 0 0 0 k4 k3 k2 k1 k0];
                Kdxx = fliplr(app.coeffx);
                assignin("base",'Kdxx',Kdxx);
                lstate.mech.cm = 1/Kdxx(1);
            end
            if app.paramtype == 3
                % [Ldxx] = [0 0 0 0 l4 l3 l2 l1 l0]; 
                Ldxx = fliplr(app.coeffx);
                assignin("base",'Ldxx',Ldxx);
                lstate.elec.Le = Ldxx(1);
            end 
                      
            assignin("base",'state',lstate);
        end

        % Value changed function: maxxEditField
        function maxxEditFieldValueChanged(app, event)
            value = app.maxxEditField.Value;
            app.xmax = value;
            updateFields(app);
        end

        % Value changed function: coef_X0EditField
        function coef_X0EditFieldValueChanged(app, event)
            value = app.coef_X0EditField.Value;
            app.coeffx(5) = value;
            updateFields(app);
        end

        % Value changed function: coef_X1EditField
        function coef_X1EditFieldValueChanged(app, event)
            value = app.coef_X1EditField.Value;
            app.coeffx(4) = value;
            updateFields(app);
        end

        % Value changed function: coef_X2EditField
        function coef_X2EditFieldValueChanged(app, event)
            value = app.coef_X2EditField.Value;
            app.coeffx(3) = value;
            updateFields(app);
        end

        % Value changed function: coef_X3EditField
        function coef_X3EditFieldValueChanged(app, event)
            value = app.coef_X3EditField.Value;
            app.coeffx(2) = value;
            updateFields(app);
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            myxx = app.traceX;
            myxx = myxx - 0.01;
            
            if myxx < -(app.xmax)
               myxx = -(app.xmax);
            end
            app.traceX = myxx;
            app.TraceXEditField.Value = myxx;
            mycoeff = app.coeffx;
            app.TraceYEditField.Value = polyval(mycoeff, myxx/100);           
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            myxx = app.traceX;
            myxx = myxx + 0.01;
            
            if myxx > (app.xmax)
                myxx = app.xmax;
            end
            app.traceX = myxx;
            app.TraceXEditField.Value = myxx;
            mycoeff = app.coeffx;
            app.TraceYEditField.Value = polyval(mycoeff, myxx/100);
        end

        % Value changed function: coef_X4EditField
        function coef_X4EditFieldValueChanged(app, event)
            value = app.coef_X4EditField.Value;
            app.coeffx(1) = value;
            updateFields(app);
        end

        % Value changed function: TraceXEditField
        function TraceXEditFieldValueChanged(app, event)
            value = app.TraceXEditField.Value;
            value = value/100;
            app.traceX = value;
            Blxx = app.coeffx;
            Byy = polyval(Blxx, value);
            app.TraceYEditField.Value = Byy;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.SlidervalueEditField.Value = changingValue;
                        
            
        end

        % Value changed function: SlidervalueEditField
        function SlidervalueEditFieldValueChanged(app, event)
            value = app.SlidervalueEditField.Value;
            switch(app.adjChoice)
                case 'shift'
                    result = choice_shift(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end                    
                case 'width'
                    result = choice_width(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end                    
                case 'plateau'
                    result = choice_plateau(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end                    
                case 'skew'
                    result = choice_skew(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end                    
                case 'tilt'
                    result = choice_tilt(app, value);
                    if(result==1)
                        temp_updateFields(app);
                    end                    
                otherwise
                    app.adjChoice = 'shift';
                    app.ShiftCheckBox.Value = 1;
                    
            end
            app.SlidervalueEditField.Value = 0;
            
        end

        % Button pushed function: SettouseButton
        function SettouseButtonPushed(app, event)
            app.coeffx = app.temp_coeffx;
            updateFields(app);
        end

        % Value changed function: Skew_gainEditField
        function Skew_gainEditFieldValueChanged(app, event)
            value = app.Skew_gainEditField.Value;
                app.skew_gain = value;
        end

        % Value changed function: invertgraphCheckBox
        function invertgraphCheckBoxValueChanged(app, event)
            value = app.invertgraphCheckBox.Value;
            app.invert_graph = value;
            cla(app.UIAxes);
            temp_updateFields(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {648, 648};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ShiftCheckBox = uicheckbox(app.LeftPanel);
            app.ShiftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShiftCheckBoxValueChanged, true);
            app.ShiftCheckBox.Text = 'Shift';
            app.WidthCheckBox = uicheckbox(app.LeftPanel);
            app.WidthCheckBox.ValueChangedFcn = createCallbackFcn(app, @WidthCheckBoxValueChanged, true);
            app.WidthCheckBox.Text = 'Width';
            app.PlateauCheckBox = uicheckbox(app.LeftPanel);
            app.PlateauCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlateauCheckBoxValueChanged, true);
            app.PlateauCheckBox.Text = 'Plateau';
            app.SkewCheckBox = uicheckbox(app.LeftPanel);
            app.SkewCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkewCheckBoxValueChanged, true);
            app.SkewCheckBox.Text = 'Skew';
            app.TiltCheckBox = uicheckbox(app.LeftPanel);
            app.TiltCheckBox.ValueChangedFcn = createCallbackFcn(app, @TiltCheckBoxValueChanged, true);
            app.TiltCheckBox.Text = 'Tilt';
            app.SliderLabel = uilabel(app.LeftPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SlidervalueEditFieldLabel = uilabel(app.LeftPanel);
            app.SlidervalueEditFieldLabel.Text = 'Slider value';
            app.SlidervalueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SlidervalueEditField.ValueChangedFcn = createCallbackFcn(app, @SlidervalueEditFieldValueChanged, true);
            app.SettouseButton = uibutton(app.LeftPanel, 'push');
            app.SettouseButton.ButtonPushedFcn = createCallbackFcn(app, @SettouseButtonPushed, true);
            app.SettouseButton.Text = 'Set to use';
            app.Skew_gainEditFieldLabel = uilabel(app.LeftPanel);
            app.Skew_gainEditFieldLabel.Text = 'Skew_gain';
            app.Skew_gainEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Skew_gainEditField.ValueChangedFcn = createCallbackFcn(app, @Skew_gainEditFieldValueChanged, true);
            app.invertgraphCheckBox = uicheckbox(app.LeftPanel);
            app.invertgraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @invertgraphCheckBoxValueChanged, true);
            app.invertgraphCheckBox.Text = 'invert graph';
            app.RightPanel = uipanel(app.GridLayout);
            app.maxxEditFieldLabel = uilabel(app.RightPanel);
            app.maxxEditFieldLabel.Text = 'max x';
            app.maxxEditField = uieditfield(app.RightPanel, 'numeric');
            app.maxxEditField.ValueChangedFcn = createCallbackFcn(app, @maxxEditFieldValueChanged, true);
            app.coef_X0EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X0EditFieldLabel.Text = 'coef_X0';
            app.coef_X0EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X0EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditFieldValueChanged, true);
            app.coef_X1EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X1EditFieldLabel.Text = 'coef_X1';
            app.coef_X1EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X1EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditFieldValueChanged, true);
            app.coef_X2EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X2EditFieldLabel.Text = 'coef_X2';
            app.coef_X2EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X2EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditFieldValueChanged, true);
            app.coef_X3EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X3EditFieldLabel.Text = 'coef_X3';
            app.coef_X3EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X3EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditFieldValueChanged, true);
            app.MeasurementsPanel = uipanel(app.RightPanel);
            app.MeasurementsPanel.Title = 'Measurements';
            app.MaxYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MaxYEditFieldLabel.Text = 'Max Y';
            app.MaxYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.MinYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MinYEditFieldLabel.Text = 'Min Y';
            app.MinYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.TraceXEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.TraceXEditFieldLabel.Text = 'Trace X';
            app.TraceXEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.TraceXEditField.ValueChangedFcn = createCallbackFcn(app, @TraceXEditFieldValueChanged, true);
            app.TraceYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.TraceYEditFieldLabel.Text = 'Trace Y';
            app.TraceYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LButton = uibutton(app.MeasurementsPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.MeasurementsPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.MaxY_XLabel = uilabel(app.MeasurementsPanel);
            app.MaxY_XLabel.Text = 'MaxY_X';
            app.MaxY_XEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.MinY_XLabel = uilabel(app.MeasurementsPanel);
            app.MinY_XLabel.Text = 'MinY_X';
            app.MinY_XEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.coef_X4EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X4EditFieldLabel.Text = 'coef_X4';
            app.coef_X4EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X4EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditFieldValueChanged, true);
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramAdjustApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end