classdef thieleSmallCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ThieleSmallCalculatorUIFigure   matlab.ui.Figure
        Panel_7                         matlab.ui.container.Panel
        selectNonlinearityButtonGroup_2  matlab.ui.container.ButtonGroup
        RmsvButton                      matlab.ui.control.RadioButton
        RmsxButton                      matlab.ui.control.RadioButton
        KxButton                        matlab.ui.control.RadioButton
        NonlinearityforKPanel           matlab.ui.container.Panel
        coef_X5EditField_3              matlab.ui.control.NumericEditField
        coef_X5EditField_3Label         matlab.ui.control.Label
        ReloadButton_3                  matlab.ui.control.Button
        coef_X4EditField_3              matlab.ui.control.NumericEditField
        coef_X4EditField_3Label         matlab.ui.control.Label
        coef_X3EditField_3              matlab.ui.control.NumericEditField
        coef_X3EditField_3Label         matlab.ui.control.Label
        coef_X0EditField_3              matlab.ui.control.NumericEditField
        coef_X0EditField_3Label         matlab.ui.control.Label
        coef_X1EditField_3              matlab.ui.control.NumericEditField
        coef_X1EditField_3Label         matlab.ui.control.Label
        coef_X2EditField_3              matlab.ui.control.NumericEditField
        coef_X2EditField_3Label         matlab.ui.control.Label
        AdjustgraphButton_3             matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        Panel_6                         matlab.ui.container.Panel
        selectNonlinearityButtonGroup   matlab.ui.container.ButtonGroup
        LxButton_3                      matlab.ui.control.RadioButton
        BlxButton                       matlab.ui.control.RadioButton
        NonlinearityforRmsPanel         matlab.ui.container.Panel
        coef_X5EditField_2              matlab.ui.control.NumericEditField
        coef_X5EditField_2Label         matlab.ui.control.Label
        ReloadButton_2                  matlab.ui.control.Button
        coef_X4EditField_2              matlab.ui.control.NumericEditField
        coef_X4EditField_2Label         matlab.ui.control.Label
        coef_X3EditField_2              matlab.ui.control.NumericEditField
        coef_X3EditField_2Label         matlab.ui.control.Label
        coef_X0EditField_2              matlab.ui.control.NumericEditField
        coef_X0EditField_2Label         matlab.ui.control.Label
        coef_X1EditField_2              matlab.ui.control.NumericEditField
        coef_X1EditField_2Label         matlab.ui.control.Label
        coef_X2EditField_2              matlab.ui.control.NumericEditField
        coef_X2EditField_2Label         matlab.ui.control.Label
        AdjustgraphButton_2             matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        TSinterpretedparamPanel         matlab.ui.container.Panel
        QtsEditField                    matlab.ui.control.NumericEditField
        QtsEditFieldLabel               matlab.ui.control.Label
        Decayrate92Label                matlab.ui.control.Label
        pifs2Qtss1EditField             matlab.ui.control.NumericEditField
        pifs2Qtss1EditFieldLabel        matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        ExWButton                       matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        RecommendationPanel             matlab.ui.container.Panel
        volmatRmdEditField              matlab.ui.control.NumericEditField
        volmatRmdEditFieldLabel         matlab.ui.control.Label
        VolboxRmdEditField              matlab.ui.control.NumericEditField
        VolboxRmdEditFieldLabel         matlab.ui.control.Label
        volumeairRmdEditField           matlab.ui.control.NumericEditField
        volumeairRmdEditFieldLabel      matlab.ui.control.Label
        GeneralpropertiesPanel          matlab.ui.container.Panel
        rhokgm3EditField                matlab.ui.control.NumericEditField
        rhokgm3EditFieldLabel           matlab.ui.control.Label
        PaEditField                     matlab.ui.control.NumericEditField
        PaLabel                         matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        gammaLabel                      matlab.ui.control.Label
        cmsEditField                    matlab.ui.control.NumericEditField
        cmsEditFieldLabel               matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        AcousticmaterialPanel           matlab.ui.container.Panel
        materialticknessmmEditField     matlab.ui.control.NumericEditField
        materialticknessmmEditFieldLabel  matlab.ui.control.Label
        Volumeairm3EditField            matlab.ui.control.NumericEditField
        Volumeairm3EditFieldLabel       matlab.ui.control.Label
        VolratioVaVmEditField           matlab.ui.control.NumericEditField
        VolratioVaVmEditFieldLabel      matlab.ui.control.Label
        materialRfraylmmEditField       matlab.ui.control.NumericEditField
        materialRfraylmmEditFieldLabel  matlab.ui.control.Label
        Volumematerialm3EditField       matlab.ui.control.NumericEditField
        Volumematerialm3EditFieldLabel  matlab.ui.control.Label
        powerPanel                      matlab.ui.container.Panel
        InputpowerWEditField            matlab.ui.control.NumericEditField
        InputpowerWEditFieldLabel       matlab.ui.control.Label
        estxmaxcmEditField              matlab.ui.control.NumericEditField
        estxmaxcmEditFieldLabel         matlab.ui.control.Label
        EnclosureInterpretedparamPanel  matlab.ui.container.Panel
        FirstmodefreqEditField          matlab.ui.control.NumericEditField
        FirstmodefreqEditFieldLabel     matlab.ui.control.Label
        EffVolumem3EditField            matlab.ui.control.NumericEditField
        EffVolumem3EditFieldLabel       matlab.ui.control.Label
        QtcEditField_2                  matlab.ui.control.NumericEditField
        QtcEditField_2Label             matlab.ui.control.Label
        FcHzEditField                   matlab.ui.control.NumericEditField
        FcHzEditFieldLabel              matlab.ui.control.Label
        SpeakerEnclosurePanel           matlab.ui.container.Panel
        boxdepthmEditField              matlab.ui.control.NumericEditField
        boxdepthmEditFieldLabel         matlab.ui.control.Label
        backaream2EditField             matlab.ui.control.NumericEditField
        backaream2Label                 matlab.ui.control.Label
        Volumeboxm3EditField            matlab.ui.control.NumericEditField
        Volumeboxm3Label                matlab.ui.control.Label
        SpeakerPortsPanel               matlab.ui.container.Panel
        portlengthmEditField            matlab.ui.control.NumericEditField
        portlengthmEditFieldLabel       matlab.ui.control.Label
        portradiusmEditField            matlab.ui.control.NumericEditField
        portradiusmEditFieldLabel       matlab.ui.control.Label
        numportsEditField               matlab.ui.control.NumericEditField
        numportsEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        ThieleSmallParametersPanel_2    matlab.ui.container.Panel
        CmsmNEditField                  matlab.ui.control.NumericEditField
        CmsmNEditFieldLabel             matlab.ui.control.Label
        RmskgsEditField                 matlab.ui.control.NumericEditField
        RmskgsEditFieldLabel            matlab.ui.control.Label
        MmdkgEditField                  matlab.ui.control.NumericEditField
        MmdkgEditFieldLabel             matlab.ui.control.Label
        MmsEditField                    matlab.ui.control.NumericEditField
        MmsEditFieldLabel               matlab.ui.control.Label
        VasLEditField                   matlab.ui.control.NumericEditField
        VasLEditFieldLabel              matlab.ui.control.Label
        Sdm2EditField                   matlab.ui.control.NumericEditField
        Sdm2EditFieldLabel              matlab.ui.control.Label
        fsHzEditField                   matlab.ui.control.NumericEditField
        fsHzEditFieldLabel              matlab.ui.control.Label
        QmsEditField                    matlab.ui.control.NumericEditField
        QmsEditFieldLabel               matlab.ui.control.Label
        QesEditField                    matlab.ui.control.NumericEditField
        QesEditFieldLabel               matlab.ui.control.Label
        ReEditField                     matlab.ui.control.NumericEditField
        ReEditFieldLabel                matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        MechanicalParametersPanel       matlab.ui.container.Panel
        inrmEditField                   matlab.ui.control.NumericEditField
        inrmEditFieldLabel              matlab.ui.control.Label
        outrmEditField                  matlab.ui.control.NumericEditField
        outrmEditFieldLabel             matlab.ui.control.Label
        xmaxcmEditField                 matlab.ui.control.NumericEditField
        xmaxcmEditFieldLabel            matlab.ui.control.Label
        rmkgsEditField                  matlab.ui.control.NumericEditField
        rmkgsEditFieldLabel             matlab.ui.control.Label
        cmmNEditField                   matlab.ui.control.NumericEditField
        cmmNEditFieldLabel              matlab.ui.control.Label
        mmkgEditField                   matlab.ui.control.NumericEditField
        mmkgEditFieldLabel              matlab.ui.control.Label
        ElectricParametersPanel         matlab.ui.container.Panel
        BlNAEditField                   matlab.ui.control.NumericEditField
        BlNAEditFieldLabel              matlab.ui.control.Label
        LeHEditField                    matlab.ui.control.NumericEditField
        LeHEditFieldLabel               matlab.ui.control.Label
        ReohmsEditField                 matlab.ui.control.NumericEditField
        ReohmsEditFieldLabel            matlab.ui.control.Label
    end

      
    properties (Access = private)
        decayRate % Description        
        Blxx % Description
        Ldxx % Description
        Kdxx % Description
        Rmxx % 
        Rmvv 
        Blxx_orig % Description
        Ldxx_orig % Description
        Kdxx_orig % Description
        graph1_select = 0; % flag indicate choice of variable to plot in graph1
        graph2_select = 0; % flag indicate choice of variable to plot in graph2
        Mar     % Acoustic Radiation Mass
        Mms     % Moving Mass of Speaker (included radiation mass)
    end
    
    properties (Access = public)
        state % param structure to be passed into and out of this app
        Rmxx_orig %
        Rmvv_orig %         
    end
    
    methods (Access = public)
        function results = calcVas(app)
            results = 1;
            app.state.Vas = app.state.mech.cm * app.state.Sd*app.state.Sd* app.state.rho*app.state.c*app.state.c;
            app.VasLEditField.Value = app.state.Vas * 1000;  % change unit to Litre
        end

        function results = updateThiele(app)
            results = 1;

            app.state = updateTSparam(app.state);
            
            app.ReohmsEditField.Value = app.state.elec.Re;
            app.ReEditField.Value = app.state.elec.Re;
            
            app.state.Qms = sqrt(app.state.mech.mm / app.state.mech.cm)/app.state.mech.rm; % sqrt( mm k )/D
            app.QmsEditField.Value = app.state.Qms;
            
            app.state.Qes = app.state.elec.Re/(app.state.elec.Bl*app.state.elec.Bl)*sqrt(app.state.mech.mm/app.state.mech.cm);            
            app.QesEditField.Value = app.state.Qes;
            
            app.state.fs = 1/(2*pi*sqrt(app.state.mech.mm*app.state.mech.cm));
            app.fsHzEditField.Value = app.state.fs;
            
            
            if app.state.mech.ir > 0.001
               app.state.Sd = pi/3*(app.state.mech.or*app.state.mech.or+app.state.mech.or*app.state.mech.ir+app.state.mech.ir*app.state.mech.ir);
            else
               app.state.Sd = pi*app.state.mech.or*app.state.mech.or;
            end
            app.Mar = 2.67 * app.state.rho * (app.state.mech.or)^3;
            app.Sdm2EditField.Value = app.state.Sd;
            app.state.Vas = app.state.mech.cm * app.state.Sd*app.state.Sd* app.state.rho*app.state.c*app.state.c;
            app.VasLEditField.Value = app.state.Vas * 1000;  % change unit to Litre
            
            app.state.Qts = 1/(1/app.state.Qes+1/app.state.Qms);
            app.QtsEditField.Value = app.state.Qts;
            
            app.MmsEditField.Value = app.Mar + app.state.mech.mm;

            % update param in Other 
            app.decayRate = 2*pi*app.state.fs / (2 * app.state.Qts);
            app.pifs2Qtss1EditField.Value = app.decayRate;
            
        end
    end


    methods (Access = public)
                       
        function processNewBlxx(app, newBlxx)
            app.Blxx_orig = newBlxx;
            app.Blxx = zeros(6,1);

            for ii=1:6
                app.Blxx(7-ii) = newBlxx(ii);
            end
            
            if app.graph1_select==0 
                displayBlxx(app, newBlxx);
            end 
        end
        
        function processNewLdxx(app, newLdxx)

            app.Ldxx_orig = newLdxx;
            app.Ldxx = zeros(6,1);

            for ii=1:6
                app.Ldxx(7-ii) = newLdxx(ii);
            end

            if app.graph1_select==1
                displayLdxx(app, newLdxx);
            end
        end
        
        function processNewKdxx(app, newKdxx)

            app.Kdxx_orig = newKdxx;
            app.Kdxx = zeros(6,1);

            for ii=1:6
                app.Kdxx(7-ii) = newKdxx(ii);
            end

            if app.graph2_select==0
                displayKdxx(app, newKdxx);
            end
        end

        function processNewRmxx(app, newRmxx)

            app.Rmxx_orig = newRmxx;
            app.Rmxx = zeros(6,1);

            for ii=1:6
                app.Rmxx(7-ii) = newRmxx(ii);
            end

            if app.graph2_select==1
                displayRmxx(app, newRmxx);
            end
        end
        function processNewRmvv(app, newRmvv)

            app.Rmvv_orig = newRmvv;
            app.Rmvv = zeros(6,1);

            for ii=1:6
                app.Rmvv(7-ii) = newRmvv(ii);
            end

            if app.graph2_select==2
                displayRmvv(app, newRmvv);
            end
        end
        
        function setSysParam(app, lstate)
            app.state = lstate;
                        
            %% elec param
            app.ReohmsEditField.Value = app.state.elec.Re;
            app.ReEditField.Value = app.state.elec.Re;            
            app.LeHEditField.Value = app.state.elec.Le;
            app.BlNAEditField.Value = app.state.elec.Bl;
            
            %% mech param
            app.mmkgEditField.Value = app.state.mech.mm;
            app.MmdkgEditField.Value = app.state.mech.mm;
            app.cmmNEditField.Value = app.state.mech.cm;
            app.CmsmNEditField.Value = app.state.mech.cm;

            app.rmkgsEditField.Value = app.state.mech.rm;
            app.RmskgsEditField.Value = app.state.mech.rm;
            app.xmaxcmEditField.Value = app.state.mech.xmax * 100;            
            app.outrmEditField.Value = app.state.mech.or;
            app.inrmEditField.Value = app.state.mech.ir; 
            
            
            %% box param
            app.backaream2EditField.Value = app.state.box.Sm;
            app.boxdepthmEditField.Value = app.state.box.vol / app.state.box.Sm;
            app.Volumeboxm3EditField.Value = app.state.box.vol;

            %% box -- ports
            app.numportsEditField.Value = app.state.box.numPorts;
            app.portradiusmEditField.Value = app.state.box.portRadius;
            app.portlengthmEditField.Value = app.state.box.portLen;

            %% box -- acoustic materials
            app.materialticknessmmEditField.Value = app.state.box.mthick;
            app.Volumeairm3EditField.Value = app.state.box.Va;
            app.VolratioVaVmEditField.Value = app.state.box.vratio;
            app.materialRfraylmmEditField.Value = app.state.box.Rf;
            app.Volumematerialm3EditField.Value = app.state.box.Vm;
                        
            %% box -- interpreted param
            app.EffVolumem3EditField.Value = app.state.box.Vab;
            app.FirstmodefreqEditField.Value = app.state.box.firstModeFreq;
            app.QtcEditField_2.Value = app.state.Qtc;
            app.FcHzEditField.Value = app.state.fc;
            
            %% box -- recommended
            app.volmatRmdEditField.Value = app.state.box.Vm_Rmd;
            app.VolboxRmdEditField.Value = app.state.box.vol_Rmd;
            app.volumeairRmdEditField.Value = app.state.box.Va_Rmd;
            
            %% power
            app.InputpowerWEditField.Value = app.state.inputPower;
            app.estxmaxcmEditField.Value = app.state.est_xmax * 100;
            
            %% general properties
            app.rhokgm3EditField.Value = app.state.rho;
            app.cmsEditField.Value = app.state.c;
            app.gammaEditField.Value = app.state.gamma;
            app.PaEditField.Value = app.state.P0;
            
            updateThiele(app);
        end
        
        function results = showBoxParam(app)
            results = 1;
            app.FcHzEditField.Value = app.state.fc;
            app.VolboxRmdEditField.Value = app.state.box.vol_Rmd;
            app.volumeairRmdEditField.Value = app.state.box.Va_Rmd;            
            app.volmatRmdEditField.Value = app.state.box.Vm_Rmd;
            app.QtcEditField_2.Value = app.state.Qtc;
            app.EffVolumem3EditField.Value = app.state.box.Vab;
            app.FirstmodefreqEditField.Value = app.state.box.firstModeFreq;
        end
        
        function results = showBoxSize(app)
            results = 1;
            app.backaream2EditField.Value = app.state.box.Sm;
            app.boxdepthmEditField.Value = app.state.box.vol/app.state.box.Sm;
            app.Volumeboxm3EditField.Value = app.state.box.vol;
            app.materialRfraylmmEditField.Value = app.state.box.Rf;
            app.materialticknessmmEditField.Value = app.state.box.mthick;
            app.Volumematerialm3EditField.Value = app.state.box.Vm;
            app.VolratioVaVmEditField.Value = app.state.box.vratio;
            app.Volumeairm3EditField.Value = app.state.box.Va;
            
        end
        
        function results = setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd)
            results = 1;
            app.state.rear_M = Mab;
            app.state.rear_C = Cab;
            app.state.rear_R = Rab;
            app.state.box.vol_Rmd = vol_Rmd;
            app.state.box.Va_Rmd = Va_Rmd;
            app.state.box.Vm_Rmd = Vm_Rmd;
            app.state.box.boxDepth_Rmd= boxDepth_Rmd; 
            
            app.VolboxRmdEditField.Value = vol_Rmd;
            app.volumeairRmdEditField.Value = Va_Rmd;
            app.volmatRmdEditField.Value = Vm_Rmd;
            
        end
        
    end
    
    methods (Access = private)
        
        function displayBlxx(app, newBlxx)
            app.coef_X0EditField_2.Value = newBlxx(1);
            app.coef_X1EditField_2.Value = newBlxx(2);
            app.coef_X2EditField_2.Value = newBlxx(3);
            app.coef_X3EditField_2.Value = newBlxx(4);
            app.coef_X4EditField_2.Value = newBlxx(5);
            app.coef_X5EditField_2.Value = newBlxx(6);

            % plot graph Blxx
            % put values into ymax, ymin, xx
            mycoeff = app.Blxx;
            strTitle = 'Bl force vs distance';
            strylabel = 'Newton / A';
            xx = app.state.mech.xmax;  % in m
            xxx = -xx: xx/10: xx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);

            cla(app.UIAxes_2);                        % Clear the axes to remove the previous line
            line(app.UIAxes_2, xcm, yyy);
            title(app.UIAxes_2, strTitle)
            grid(app.UIAxes_2, 'on')
            xlabel(app.UIAxes_2, 'position (cm)');
            ylabel(app.UIAxes_2, strylabel );            
        end

        function displayLdxx(app, newLdxx)
                app.coef_X0EditField_2.Value = newLdxx(1);
                app.coef_X1EditField_2.Value = newLdxx(2);
                app.coef_X2EditField_2.Value = newLdxx(3);
                app.coef_X3EditField_2.Value = newLdxx(4);
                app.coef_X4EditField_2.Value = newLdxx(5);
                app.coef_X5EditField_2.Value = newLdxx(6);
                
                % plot graph Ldxx           
                % put values into ymax, ymin, xx            
                mycoeff = app.Ldxx;
                strTitle = 'Inductance vs distance';
                strylabel = 'mH';
                xx = app.state.mech.xmax;
                xxx = -xx: xx/10: xx;
                xcm = xxx*100;
                yyy = polyval(mycoeff, xxx);
    
                % disabled temporarily below
                cla(app.UIAxes_2);                        % Clear the axes to remove the previous line
                line(app.UIAxes_2, xcm, yyy);
                title(app.UIAxes_2, strTitle)
                grid(app.UIAxes_2, 'on')
                xlabel(app.UIAxes_2, 'position (cm)');
                ylabel(app.UIAxes_2, strylabel );                    
        end
        
        function displayKdxx(app, newKdxx)
                app.coef_X0EditField_3.Value = newKdxx(1);
                app.coef_X1EditField_3.Value = newKdxx(2);
                app.coef_X2EditField_3.Value = newKdxx(3);
                app.coef_X3EditField_3.Value = newKdxx(4);
                app.coef_X4EditField_3.Value = newKdxx(5);
                app.coef_X5EditField_3.Value = newKdxx(6);
                
                % plot graph Kdxx           
                % put values into ymax, ymin, xx
                mycoeff = app.Kdxx;
                strTitle = 'Stiffness vs distance';
                strylabel = 'Stiffness (N/m)';
                xx = app.state.mech.xmax;
                xxx = -xx: xx/10: xx;
                xcm = xxx*100;
                yyy = polyval(mycoeff, xxx);

                cla(app.UIAxes_3);                        % Clear the axes to remove the previous line
                line(app.UIAxes_3, xcm, yyy);
                title(app.UIAxes_3, strTitle)
                grid(app.UIAxes_3, 'on')
                xlabel(app.UIAxes_3, 'position (cm)');
                ylabel(app.UIAxes_3, strylabel ); 

        end

        function displayRmxx(app, newRmxx)
                app.coef_X0EditField_3.Value = newRmxx(1);
                app.coef_X1EditField_3.Value = newRmxx(2);
                app.coef_X2EditField_3.Value = newRmxx(3);
                app.coef_X3EditField_3.Value = newRmxx(4);
                app.coef_X4EditField_3.Value = newRmxx(5);
                app.coef_X5EditField_3.Value = newRmxx(6);
                
                % plot graph Rmxx           
                % put values into ymax, ymin, xx
                mycoeff = app.Rmxx;
                strTitle = 'Mechanical loss vs distance';
                strylabel = 'Loss ';
                xx = app.state.mech.xmax;
                xxx = -xx: xx/10: xx;
                xcm = xxx*100;
                yyy = polyval(mycoeff, xxx);
    
                cla(app.UIAxes_3);                        % Clear the axes to remove the previous line
                line(app.UIAxes_3, xcm, yyy);
                title(app.UIAxes_3, strTitle)
                grid(app.UIAxes_3, 'on')
                xlabel(app.UIAxes_3, 'position (cm)');
                ylabel(app.UIAxes_3, strylabel );       

        end
        function displayRmvv(app, newRmvv)
            app.coef_X0EditField_3.Value = newRmvv(1);
            app.coef_X1EditField_3.Value = newRmvv(2);
            app.coef_X2EditField_3.Value = newRmvv(3);
            app.coef_X3EditField_3.Value = newRmvv(4);
            app.coef_X4EditField_3.Value = newRmvv(5);
            app.coef_X5EditField_3.Value = newRmvv(6);

            % plot graph Rmvv
            % put values into ymax, ymin, xx
            mycoeff = app.Rmvv;
            strTitle = 'Mechanical loss vs velocity';
            strylabel = 'Loss ';
            xx = app.state.mech.xmax;
            xxx = -xx: xx/10: xx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);

            cla(app.UIAxes_3);                        % Clear the axes to remove the previous line
            line(app.UIAxes_3, xcm, yyy);
            title(app.UIAxes_3, strTitle)
            grid(app.UIAxes_3, 'on')
            xlabel(app.UIAxes_3, 'velocity (cm/s)');
            ylabel(app.UIAxes_3, strylabel );

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            init_speaker_st_nl_cload;  % loading the default parameters

            assignin('base','state',state)
            assignin('base','Blxx',Blxx)
            assignin('base','Kdxx',Kdxx)
            assignin('base','Ldxx',Ldxx)
            assignin('base','Rmxx',Rmxx)
            assignin('base','Rmvv',Rmvv)
            
            setSysParam(app, state);
            
            % put values into Blxx
            newBlxx = evalin('base', 'Blxx');
            processNewBlxx(app, newBlxx);
                        
            % put values into Ldxx
            newLdxx = evalin('base', 'Ldxx');
            processNewLdxx(app, newLdxx);
                        
            % put values into Kdxx
            newKdxx = evalin('base', 'Kdxx');
            processNewKdxx(app, newKdxx);

            % put values into Rmxx
            newRmxx = evalin('base', 'Rmxx');
            processNewRmxx(app, newRmxx);      

            % put values into Rmvv
            newRmvv = evalin('base', 'Rmvv');
            processNewRmvv(app, newRmvv);      
            
        end

        % Value changed function: ReohmsEditField
        function ReohmsEditFieldValueChanged(app, event)
            value = app.ReohmsEditField.Value;
            app.state.elec.Re = value;
            updateThiele(app);      
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
        end

        % Close request function: ThieleSmallCalculatorUIFigure
        function ThieleSmallCalculatorUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: LeHEditField
        function LeHEditFieldValueChanged(app, event)

            value = app.LeHEditField.Value;
            app.state.elec.Le = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Ldxx_orig(1) = app.state.elec.Le;
            processNewLdxx(app, app.Ldxx_orig);
        end

        % Value changed function: BlNAEditField
        function BlNAEditFieldValueChanged(app, event)
            value = app.BlNAEditField.Value;
            app.state.elec.Bl = value;
            updateThiele(app);        
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Blxx_orig(1) = app.state.elec.Bl;
            processNewBlxx(app, app.Blxx_orig);
        end

        % Value changed function: mmkgEditField
        function mmkgEditFieldValueChanged(app, event)
            value = app.mmkgEditField.Value;
            app.state.mech.mm = value;
            app.MmdkgEditField.Value = value;
            updateThiele(app);
            app.state.fc = calFc_fsQtcQts(app.state);
            app.MmsEditField.Value = value + app.Mar;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: cmmNEditField
        function cmmNEditFieldValueChanged(app, event)
            value = app.cmmNEditField.Value;
            app.state.mech.cm = value;
            app.CmsmNEditField.Value = value;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Kdxx_orig(1) = 1/app.state.mech.cm;
            processNewKdxx(app, app.Kdxx_orig);            
        end

        % Value changed function: rmkgsEditField
        function rmkgsEditFieldValueChanged(app, event)
            value = app.rmkgsEditField.Value;
            app.RmskgsEditField.Value = value;
            app.state.mech.rm = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            

            % update nonlinear graph
            app.Rmxx_orig(1) = app.state.mech.rm;
            processNewRmxx(app, app.Rmxx_orig);
            app.Rmvv_orig(1) = app.state.mech.rm;
            processNewRmvv(app, app.Rmvv_orig);            
        end

        % Value changed function: xmaxcmEditField
        function xmaxcmEditFieldValueChanged(app, event)
            value = app.xmaxcmEditField.Value;
            app.state.mech.xmax = value /100;            
        end

        % Value changed function: outrmEditField
        function outrmEditFieldValueChanged(app, event)
            value = app.outrmEditField.Value;
            app.state.mech.or = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: inrmEditField
        function inrmEditFieldValueChanged(app, event)
            value = app.inrmEditField.Value;
            app.state.mech.ir = value;
            updateThiele(app);    
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
        end

        % Value changed function: numportsEditField
        function numportsEditFieldValueChanged(app, event)
            %value = app.numportsEditField.Value;
            % value cannot be modified
            app.numportsEditField.Value = 0;            
        end

        % Value changed function: portradiusmEditField
        function portradiusmEditFieldValueChanged(app, event)
            %value = app.portradiusmEditField.Value;
            % value cannot be modified
            app.portradiusmEditField.Value = 0;            
        end

        % Value changed function: portlengthmEditField
        function portlengthmEditFieldValueChanged(app, event)
            %value = app.portlengthmEditField.Value;
            % value cannot be modified
            app.portlengthmEditField.Value = 0;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

           % Open file dialog to choose save location and file name
            [file, path] = uiputfile('*.txt', 'Save as');
            if isequal(file, 0) || isequal(path, 0)
                disp('User canceled the save operation.');
            else
                % Construct full file path
                fullFileName = fullfile(path, file);
                % Save data to the specified file
                fid = fopen(fullFileName, 'w');
                if fid ~= -1
                    dataToSave = 'ThieleSmallCalc data';
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['elec.Re = ' num2str(app.state.elec.Re)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['elec.Le = ' num2str(app.state.elec.Le)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['elec.Bl = ' num2str(app.state.elec.Bl)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.mm = ' num2str(app.state.mech.mm)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.cm = ' num2str(app.state.mech.cm)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.rm = ' num2str(app.state.mech.rm)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.xmax = ' num2str(app.state.mech.xmax)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Blxx = ' num2str(app.Blxx_orig)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Kdxx = ' num2str(app.Kdxx_orig)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Ldxx = ' num2str(app.Ldxx_orig)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Rmxx = ' num2str(app.Rmxx_orig)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Rmvv = ' num2str(app.Rmvv_orig)];
                    fprintf(fid, '%s\n', dataToSave);                    

                    dataToSave = ['rho = ' num2str(app.state.rho)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['c = ' num2str(app.state.c)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['gamma = ' num2str(app.state.gamma)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['P0 = ' num2str(app.state.P0)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.or = ' num2str(app.state.mech.or)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['mech.ir = ' num2str(app.state.mech.ir)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.pressure_factor = ' num2str(app.state.box.pressure_factor)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.pressure_eff = ' num2str(app.state.box.pressure_eff)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.vratio = ' num2str(app.state.box.vratio)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.beta = ' num2str(app.state.box.beta)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Rf = ' num2str(app.state.box.Rf)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.mthick = ' num2str(app.state.box.mthick)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Sm = ' num2str(app.state.box.Sm)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.vol = ' num2str(app.state.box.vol)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Va = ' num2str(app.state.box.Va)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Vm = ' num2str(app.state.box.Vm)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.rear_M = ' num2str(app.state.box.rear_M)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.rear_C = ' num2str(app.state.box.rear_C)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.rear_R = ' num2str(app.state.box.rear_R)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.boxDepth_Rmd = ' num2str(app.state.box.boxDepth_Rmd)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.boxDepth = ' num2str(app.state.box.boxDepth)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.vol_Rmd = ' num2str(app.state.box.vol_Rmd)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Va_Rmd = ' num2str(app.state.box.Va_Rmd)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Vm_Rmd = ' num2str(app.state.box.Vm_Rmd)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.Vab = ' num2str(app.state.box.Vab)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.numPorts = ' num2str(app.state.box.numPorts)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.portRadius = ' num2str(app.state.box.portRadius)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.portLen = ' num2str(app.state.box.portLen)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.firstModeFreq = ' num2str(app.state.box.firstModeFreq)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.front_M = ' num2str(app.state.box.front_M)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.wavNum = ' num2str(app.state.box.wavNum)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['box.front_R = ' num2str(app.state.box.front_R)];
                    fprintf(fid, '%s\n', dataToSave);

                    dataToSave = ['fc = ' num2str(app.state.fc)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Qmb = ' num2str(app.state.Qmb)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['Qtc = ' num2str(app.state.Qtc)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['est_xmax = ' num2str(app.state.est_xmax)];
                    fprintf(fid, '%s\n', dataToSave);
                    dataToSave = ['inputPower = ' num2str(app.state.inputPower)];
                    fprintf(fid, '%s\n', dataToSave);

                    
                    fclose(fid);
                    disp(['Data saved to: ', fullFileName]);
                else
                    disp('Error opening file for writing.');
                end
            end            
                        
            
        end

        % Value changed function: ReEditField
        function ReEditFieldValueChanged(app, event)
            value = app.ReEditField.Value;
            app.state.elec.Re = value;
            app.ReohmsEditField.Value = value;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: QesEditField
        function QesEditFieldValueChanged(app, event)
            value = app.QesEditField.Value;
            app.state.Qes = value;
            app.state.elec.Bl = sqrt(app.state.elec.Re * sqrt(app.state.mech.mm / app.state.mech.cm) / app.state.Qes);
            app.BlNAEditField.Value = app.state.elec.Bl;
            updateThiele(app);     
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
            
            % update nonlinear graph
            app.Blxx_orig(1) = app.state.elec.Bl;
            processNewBlxx(app, app.Blxx_orig);
        end

        % Value changed function: QmsEditField
        function QmsEditFieldValueChanged(app, event)
            value = app.QmsEditField.Value;
            app.state.Qms = value;
            app.state.mech.rm = sqrt(app.state.mech.mm / app.state.mech.cm) / app.state.Qms;
            app.rmkgsEditField.Value = app.state.mech.rm;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: fsHzEditField
        function fsHzEditFieldValueChanged(app, event)
            value = app.fsHzEditField.Value;
            app.state.fs = value;
            app.state.mech.cm = (1/(2*pi*app.state.fs))*(1/(2*pi*app.state.fs))/ app.state.mech.mm;  
            app.cmmNEditField.Value = app.state.mech.cm;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Kdxx_orig(1) = 1/app.state.mech.cm;
            processNewKdxx(app, app.Kdxx_orig);            
            
        end

        % Value changed function: Sdm2EditField
        function Sdm2EditFieldValueChanged(app, event)
            value = app.Sdm2EditField.Value;
            app.state.Sd = value;
  %          app.area_scale = (app.Sd/pi)/(app.state.mech.or*app.state.mech.or);
            calcVas(app); 
                        
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: VasLEditField
        function VasLEditFieldValueChanged(app, event)
            %value = app.VasLEditField.Value;
            % change is not allowed
            app.VasLEditField.Value = app.state.Vas;
        end

        % Value changed function: QtsEditField
        function QtsEditFieldValueChanged(app, event)
            % value = app.QtsEditField.Value;
            % change is not allowed            
            app.QtsEditField.Value = app.state.Qts;            
        end

        % Value changed function: pifs2Qtss1EditField
        function pifs2Qtss1EditFieldValueChanged(app, event)
            % value = app.pifs2Qtss1EditField.Value;
            % change is not allowed            
            app.pifs2Qtss1EditField.Value = app.decayRate;            
        end

        % Value changed function: coef_X0EditField_2
        function coef_X0EditField_2ValueChanged(app, event)
            %value = app.coef_X0EditField_2.Value;
            % change is not allowed 
            % app.coef_X0EditField_2.Value = app.Ldxx(5);            
        end

        % Value changed function: coef_X1EditField_2
        function coef_X1EditField_2ValueChanged(app, event)
            %value = app.coef_X1EditField_2.Value;
            % change is not allowed 
            % app.coef_X1EditField_2.Value = app.Ldxx(4);            
        end

        % Value changed function: coef_X2EditField_2
        function coef_X2EditField_2ValueChanged(app, event)
            %value = app.coef_X2EditField_2.Value;
            % change is not allowed 
            % app.coef_X2EditField_2.Value = app.Ldxx(3);            
        end

        % Value changed function: coef_X3EditField_2
        function coef_X3EditField_2ValueChanged(app, event)
            %value = app.coef_X3EditField_2.Value;
            % change is not allowed 
            % app.coef_X3EditField_2.Value = app.Ldxx(2);
        end

        % Value changed function: coef_X4EditField_2
        function coef_X4EditField_2ValueChanged(app, event)
            %value = app.coef_X4EditField_2.Value;
            % change is not allowed 
            % app.coef_X4EditField_2.Value = app.Ldxx(1);            
        end

        % Value changed function: coef_X0EditField_3
        function coef_X0EditField_3ValueChanged(app, event)
            %value = app.coef_X0EditField_3.Value;
            % change is not allowed 
            % app.coef_X0EditField_3.Value = app.Kdxx(5);            
        end

        % Value changed function: coef_X1EditField_3
        function coef_X1EditField_3ValueChanged(app, event)
            %value = app.coef_X1EditField_3.Value;
            % change is not allowed 
            % app.coef_X1EditField_3.Value = app.Kdxx(4);            
        end

        % Value changed function: coef_X2EditField_3
        function coef_X2EditField_3ValueChanged(app, event)
            %value = app.coef_X2EditField_3.Value;
            % change is not allowed 
            % app.coef_X2EditField_3.Value = app.Kdxx(3);             
        end

        % Value changed function: coef_X3EditField_3
        function coef_X3EditField_3ValueChanged(app, event)
            %value = app.coef_X3EditField_3.Value;
            % change is not allowed 
            % app.coef_X3EditField_3.Value = app.Kdxx(2);             
        end

        % Value changed function: coef_X4EditField_3
        function coef_X4EditField_3ValueChanged(app, event)
            %value = app.coef_X4EditField_3.Value;
            % change is not allowed 
            % app.coef_X4EditField_3.Value = app.Kdxx(1);             
        end

        % Button pushed function: AdjustgraphButton_2
        function AdjustgraphButton_2Pushed(app, event)
            if app.graph1_select==0
                strTitle = 'Bl force vs distance';
                strylabel = 'Newton / A';            
                paramtype = 1;  % 1 = Bl, 2 = K, 3 = L
                lBlxx = app.Blxx_orig;
                lxmax = app.state.mech.xmax;
                paramAdjustApp(lxmax, lBlxx, paramtype , strTitle, strylabel, app);
            else
                strTitle = 'Inductance vs distance';
                strylabel = 'H';
                paramtype = 3;  % 1 = Bl, 2 = K, 3 = L, 4 = Rms
                paramAdjustApp(app.state.mech.xmax, app.Ldxx_orig, paramtype , strTitle, strylabel, app);            
            end
        end

        % Button pushed function: AdjustgraphButton_3
        function AdjustgraphButton_3Pushed(app, event)
            if app.graph2_select==0
                strTitle = 'Stiffness vs distance';
                strylabel = 'Stiffness (N/m)';
                paramtype = 2;  % 1 = Bl, 2 = K, 3 = L, 4 = Rms
                paramAdjustApp(app.state.mech.xmax, app.Kdxx_orig, paramtype , strTitle, strylabel, app);
            elseif app.graph2_select==1
                strTitle = 'Mechanical damping vs distance';
                strylabel = 'kg/s';
                paramtype = 4;  % 1 = Bl, 2 = K, 3 = L, 4 = Rms
                paramAdjustApp(app.state.mech.xmax, app.Rmxx_orig, paramtype , strTitle, strylabel, app);
            else
                strTitle = 'Mechanical damping vs velocity';
                strylabel = 'kg/s';
                paramtype = 5;  % 1 = Bl, 2 = K, 3 = L, 4 = Rms, 5 = Rmvv
                paramAdjustApp(app.state.mech.xmax, app.Rmvv_orig, paramtype , strTitle, strylabel, app);                
            end
        end

        % Button pushed function: ReloadButton_2
        function ReloadButton_2Pushed(app, event)

            if app.graph1_select==0
                newBlxx = evalin('base', 'Blxx');

                app.state.elec.Bl = newBlxx(1);
                setSysParam(app, app.state);
                processNewBlxx(app, newBlxx);
            else

                newLdxx = evalin('base', 'Ldxx');

                app.state.elec.Le = newLdxx(1);
                setSysParam(app, app.state);
                processNewLdxx(app, newLdxx);

            end
        end

        % Button pushed function: ReloadButton_3
        function ReloadButton_3Pushed(app, event)

            if app.graph2_select==0
                newKdxx = evalin('base', 'Kdxx');

                app.state.mech.cm = 1/newKdxx(1);
                setSysParam(app, app.state);
                processNewKdxx(app, newKdxx);
            elseif app.graph2_select==1
                newRmxx = evalin('base', 'Rmxx');
                
                app.state.mech.rm = newRmxx(1);
                setSysParam(app, app.state);
                processNewRmxx(app, newRmxx);

                newRmvv = app.Rmvv_orig;
                newRmvv(1) = newRmxx(1);
                processNewRmvv(app, newRmvv);
            else
                newRmvv = evalin('base', 'Rmvv');

                app.state.mech.rm = newRmvv(1);
                setSysParam(app, app.state);
                processNewRmvv(app, newRmvv);

                newRmxx = app.Rmxx_orig;
                newRmxx(1) = newRmvv(1);
                processNewRmxx(app, newRmxx);                
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)

            % Open a file dialog to select a file
            [file, path] = uigetfile({'*.txt', 'All Supported Files (*.txt)'; '*.*', 'All Files (*.*)'}, ...
                'Select a File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullFileName = fullfile(path, file);
               % disp(['User selected ', fullFileName]);

                fileContent = fileread(fullFileName);

                % Split the content into lines
                lines = strsplit(fileContent, '\n');

                % check if it is symmetry data file
                if contains(lines{1}, 'Symmetry', 'IgnoreCase', false) % Case sensitive search
                    [mm,airParam,nonlinParam,nonlinL] = extractTablesFromFile(lines);

                    lstate = app.state;
                    lstate.elec.Re = airParam.Re{1};  % ohm
                    if strcmp(airParam.Le1k{2},'mH')
                        lstate.elec.Le = airParam.Le1k{1}/1000;  % H
                    else
                        lstate.elec.Le = airParam.Le1k{1};
                    end
                    lstate.elec.Bl = nonlinParam.BL(airParam.Test{1});  % N/A

                    lstate.mech.mm =  str2double(mm{1})/1000;  % kg
                    lstate.mech.cm = nonlinParam.Cms(airParam.Test{1})/1000;  % m/N
                    lstate.mech.rm = nonlinParam.Rms(airParam.Test{1});  % kg/s

                    xData = nonlinParam.("X-DC")/1000;

                    lxmax = max(abs(xData));
                    lstate.mech.xmax = lxmax;
                    app.xmaxcmEditField.Value = lxmax*100;

                    %% process new BL data

                    BLdata = nonlinParam.BL;                    
                    % resample to uniform sampled data
                    fs = 1/0.0001; % sample at 0.1 cm
                    [y,ty] = resample(BLdata,xData,fs,1,1);
                
                    % interpolate to extend the input domain
                    new_xdata = -lxmax:1/fs:lxmax; % Interpolating from 0.4 to 0.7
                    new_ydata = interp1(ty, y, new_xdata, 'linear', 'extrap');
                
                    newBlxx = polyfit(new_xdata, new_ydata, 5);
                    newBlxx = fliplr(newBlxx);

                    %% process new Kms data

                    Kdata = nonlinParam.Kms;     
                    % resample to uniform sampled data
                    fs = 1/0.0001; % sample at 0.1 cm
                    [y,ty] = resample(Kdata,xData,fs,1,1);
                
                    % interpolate to extend the input domain
                    new_xdata = -lxmax:1/fs:lxmax; % Interpolating from 0.4 to 0.7
                    new_ydata = interp1(ty, y, new_xdata, 'linear', 'extrap');
                     
                    newKdxx = polyfit(new_xdata, new_ydata, 5);
                    newKdxx = fliplr(newKdxx);

                   %% process new Rms data

                    Rdata = nonlinParam.Rms;     
                    % resample to uniform sampled data
                    fs = 1/0.0001; % sample at 0.1 cm
                    [y,ty] = resample(Rdata,xData,fs,1,1);
                
                    % interpolate to extend the input domain
                    new_xdata = -lxmax:1/fs:lxmax; % Interpolating from 0.4 to 0.7
                    new_ydata = interp1(ty, y, new_xdata, 'linear', 'extrap');
                     
                    newRmxx = polyfit(new_xdata, new_ydata, 5);
                    newRmxx = fliplr(newRmxx);

                    %% process new L data
                    Ldata = nonlinL.Le1k;
                    
                    % resample to uniform sampled data
                    fs = 1/0.0001; % sample at 0.1 cm
                    if Ldata(end)>Ldata(end-1)
                        [y,ty] = resample(Ldata(1:end-1),xData(1:end-1),fs,1,1);
                    else
                        [y,ty] = resample(Ldata,xData,fs,1,1);
                    end
                    % interpolate to extend the input domain
                    new_xdata = -lxmax:1/fs:lxmax; % Interpolating from 0.4 to 0.7
                    new_ydata = interp1(ty, y, new_xdata, 'linear', 'extrap');

                    newLdxx = polyfit(new_xdata, new_ydata, 5);
                    newLdxx = fliplr(newLdxx);
            
                    %%                     
                    setSysParam(app, lstate);
                    
                    % put values into Blxx
                    processNewBlxx(app, newBlxx);

                    % put values into Ldxx
                    processNewLdxx(app, newLdxx);
                                
                    % put values into Kdxx
                    processNewKdxx(app, newKdxx);

                    % put values into Rmxx
                    processNewRmxx(app, newRmxx);

                    % put values into Rmvv
                    newRmvv = newRmxx;
                    newRmvv(2:end) = 0;    % there is no Rmvv
                    processNewRmvv(app, newRmvv);
                    

                else
                    uialert(app.ThieleSmallCalculatorUIFigure, 'Only support Dayton DATS LA symmetry data format.', 'Error: File format error');
                end
            end  % user cancel

        end

        % Value changed function: rhokgm3EditField
        function rhokgm3EditFieldValueChanged(app, event)
            value = app.rhokgm3EditField.Value;
            app.state.rho = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: cmsEditField
        function cmsEditFieldValueChanged(app, event)
            value = app.cmsEditField.Value;
            app.state.c = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: gammaEditField
        function gammaEditFieldValueChanged(app, event)
            value = app.gammaEditField.Value;
            app.state.gamma = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: PaEditField
        function PaEditFieldValueChanged(app, event)
            value = app.PaEditField.Value;
            app.state.box.P0 = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('paramsPanelHelp.html')
        end

        % Value changed function: backaream2EditField
        function backaream2EditFieldValueChanged(app, event)
            value = app.backaream2EditField.Value;
            app.state.box.Vm = app.state.box.Vm * value/app.state.box.Sm;
            app.state.box.Sm = value;
            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, Qmb] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;            
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            app.EffVolumem3EditField.Value = Vab;
            showBoxSize(app);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc;
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            
        end

        % Value changed function: boxdepthmEditField
        function boxdepthmEditFieldValueChanged(app, event)
            value = app.boxdepthmEditField.Value;
            app.state.box.boxDepth = value;
            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);            
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);             
%            app.state.boxDepth = app.state.box.vol / app.state.box.Sm;
            app.state.box.firstModeFreq = app.state.c / (2* app.state.box.boxDepth);
            %app.FirstmodefreqEditField.Value = app.state.firstModeFreq;
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            %app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: Volumeboxm3EditField
        function Volumeboxm3EditFieldValueChanged(app, event)
            value = app.Volumeboxm3EditField.Value;
            app.state.box.vol = value;
            app.state.box.boxDepth = value / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, ~, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);             
%            app.state.boxDepth = app.state.box.vol / app.state.box.Sm;
            app.state.firstModeFreq = app.state.c / (2* app.state.box.boxDepth);
%            app.FirstmodefreqEditField.Value = app.state.firstModeFreq;
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
%            app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: QtcEditField_2
        function QtcEditField_2ValueChanged(app, event)
            value = app.QtcEditField_2.Value;
            app.state.Qtc = value;
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            
        end

        % Value changed function: EffVolumem3EditField
        function EffVolumem3EditFieldValueChanged2(app, event)
            %value = app.EffVolumem3EditField.Value;
            % cannot be modified
            app.EffVolumem3EditField.Value = app.state.box.Vab;
        end

        % Value changed function: FcHzEditField
        function FcHzEditFieldValueChanged(app, event)
            value = app.FcHzEditField.Value;            
            app.state.fc = value;
            Qtc = calQtc_FcFsQts(app.state);
            app.state.Qtc = Qtc;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
        end

        % Value changed function: FirstmodefreqEditField
        function FirstmodefreqEditFieldValueChanged(app, event)
            value = app.FirstmodefreqEditField.Value;
            app.FirstmodefreqEditField.Value = value;
            app.state.box.boxDepth = app.state.c / (2* value);

            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: materialRfraylmmEditField
        function materialRfraylmmEditFieldValueChanged(app, event)
            value = app.materialRfraylmmEditField.Value;
            app.state.box.Rf = value;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            %app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            %app.FcHzEditField.Value = fc;
            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: materialticknessmmEditField
        function materialticknessmmEditFieldValueChanged(app, event)
            value = app.materialticknessmmEditField.Value;
            
            if value <= 1
                  app.state.box.Rf = 412*3;
                  app.state.box.mthick = 1;
            end
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: VolratioVaVmEditField
        function VolratioVaVmEditFieldValueChanged(app, event)
            value = app.VolratioVaVmEditField.Value;
            if value < 0
                value = app.state.box.vratio;
            end
            
            app.state.box.vratio = value;
            app.state.box.Vm = app.state.box.vol / (1+value);  % eq. 7.22
            
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;            
            %app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: Volumematerialm3EditField
        function Volumematerialm3EditFieldValueChanged(app, event)
            value = app.Volumematerialm3EditField.Value;
            app.state.box.Vm = value;
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;            
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: Volumeairm3EditField
        function Volumeairm3EditFieldValueChanged(app, event)
            value = app.Volumeairm3EditField.Value;
            app.state.box.Va = value;
            app.state.box.Vm = app.state.box.vol - app.state.box.Va;
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: InputpowerWEditField
        function InputpowerWEditFieldValueChanged(app, event)
            value = app.InputpowerWEditField.Value;
            Eg = sqrt(2 * app.state.elec.Re * value); % v = sqrt( input power x R x 2)
            app.state.est_xmax = EstXmax_fromTSEgVab(app.state, Eg, app.state.box.Vab);
            app.estxmaxcmEditField.Value = app.state.est_xmax;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Open a file dialog to select a file
            [file, path] = uigetfile({'*.txt', 'All Supported Files (*.txt)'; '*.*', 'All Files (*.*)'}, ...
                'Select a File');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
                fullFileName = fullfile(path, file);
                disp(['User selected ', fullFileName]);

                fileContent = fileread(fullFileName);

                % Split the content into lines
                lines = strsplit(fileContent, '\n');

                % check if it is symmetry data file
                if contains(lines{1}, 'ThieleSmallCalc data', 'IgnoreCase', false) % Case sensitive search

                    % Initialize a structure to hold the read values
                    data = struct('elec', struct(), 'mech', struct(), 'box', struct());
                    for ll=2:length(lines)
                        line = lines(ll); % Get the current line
                        %if ischar(line) % Check if the line is valid
                            % Use sscanf to extract the variable name and value
                            varName = sscanf(line{1}, '%[^=]'); % Get the variable name
                            value = sscanf(line{1}, '%*[^=]= %f'); % Get the numeric value
                    
                            % Store the value in the structure using the variable name
                            if ~(isempty(varName))
                                % Determine the structure field based on the variable name
                                if startsWith(varName, 'elec.')
                                    data.elec.(strtrim(varName(6:end))) = value; % Remove 'elec.'
                                elseif startsWith(varName, 'mech.')
                                    data.mech.(strtrim(varName(6:end))) = value; % Remove 'mech.'
                                elseif startsWith(varName, 'box.')
                                    data.box.(strtrim(varName(5:end))) = value; % Remove 'box.'
                                elseif startsWith(varName, 'Blxx')                                   
                                    tmpstr = split(line{1}, '=');
                                    % Split the string at the '=' character and trim whitespace
                                    numericPart = strtrim(tmpstr(2));
                                    % Use str2double to convert the space-separated numbers
                                    numericArray = str2double(strsplit(numericPart{1}));
                                    data.Blxx = numericArray;
                                elseif startsWith(varName, 'Ldxx')                                   
                                    tmpstr = split(line{1}, '=');
                                    % Split the string at the '=' character and trim whitespace
                                    numericPart = strtrim(tmpstr(2));
                                    % Use str2double to convert the space-separated numbers
                                    numericArray = str2double(strsplit(numericPart{1}));
                                    data.Ldxx = numericArray;
                                elseif startsWith(varName, 'Kdxx')                                   
                                    tmpstr = split(line{1}, '=');
                                    % Split the string at the '=' character and trim whitespace
                                    numericPart = strtrim(tmpstr(2));
                                    % Use str2double to convert the space-separated numbers
                                    numericArray = str2double(strsplit(numericPart{1}));
                                    data.Kdxx = numericArray;
                                elseif startsWith(varName, 'Rmxx')                                   
                                    tmpstr = split(line{1}, '=');
                                    % Split the string at the '=' character and trim whitespace
                                    numericPart = strtrim(tmpstr(2));
                                    % Use str2double to convert the space-separated numbers
                                    numericArray = str2double(strsplit(numericPart{1}));
                                    data.Rmxx = numericArray;
                                elseif startsWith(varName, 'Rmvv')                                   
                                    tmpstr = split(line{1}, '=');
                                    % Split the string at the '=' character and trim whitespace
                                    numericPart = strtrim(tmpstr(2));
                                    % Use str2double to convert the space-separated numbers
                                    numericArray = str2double(strsplit(numericPart{1}));
                                    data.Rmvv = numericArray;                                    
                                else
                                    data.(strtrim(varName)) = value;
                                end
                            end
                        %end        
                    end 
                    %disp('done loading')
                    setSysParam(app, data);
                    
                    % put values into Blxx
                    newBlxx = data.Blxx; 
                    processNewBlxx(app, newBlxx);
                                
                    % put values into Ldxx
                    newLdxx = data.Ldxx;
                    processNewLdxx(app, newLdxx);
                                
                    % put values into Kdxx
                    newKdxx = data.Kdxx;
                    processNewKdxx(app, newKdxx);

                    % put values into Rmxx
                    newRmxx = data.Rmxx;
                    processNewRmxx(app, newRmxx);

                    % put values into Rmvv
                    newRmvv = data.Rmvv;
                    processNewRmxx(app, newRmvv);
                    
                    % save to workspace so that the reload button works
                    assignin('base','state',data)
                    assignin('base','Blxx',newBlxx)
                    assignin('base','Kdxx',newKdxx)
                    assignin('base','Ldxx',newLdxx)
                    assignin('base','Rmxx',newRmxx)
                    assignin('base','Rmvv',newRmvv)

                end
            end

        end

        % Button pushed function: ExWButton
        function ExWButtonPushed(app, event)
            assignin('base','state',app.state)
            assignin('base','Blxx',app.Blxx_orig)
            assignin('base','Kdxx',app.Kdxx_orig)
            assignin('base','Ldxx',app.Ldxx_orig)
            assignin('base','Rmxx',app.Rmxx_orig)
            assignin('base','Rmvv',app.Rmvv_orig)

        end

        % Selection changed function: selectNonlinearityButtonGroup
        function graph1_select_callback(app, event)
            selectedButton = app.selectNonlinearityButtonGroup.SelectedObject;
            if strcmp('Bl(x)',selectedButton.Text) 
                app.graph1_select = 0;        % set flag to indicate what is being displayed

                newBlxx = app.Blxx *0;        % flip the index 
                for ii=1:6
                    newBlxx(ii) = app.Blxx(7-ii);
                end

                displayBlxx(app, newBlxx);      
            else
                app.graph1_select = 1;       % set flag to indicate what is being displayed
        
                newLdxx = app.Ldxx *0;      % flip the index 
                for ii=1:6
                    newLdxx(ii) = app.Ldxx(7-ii);
                end

                displayLdxx(app, newLdxx);  
            end

        end

        % Selection changed function: selectNonlinearityButtonGroup_2
        function graph2_select_callback(app, event)
            selectedButton = app.selectNonlinearityButtonGroup_2.SelectedObject;
            if strcmp('K(x)',selectedButton.Text) 
                app.graph2_select = 0;        % set flag to indicate what is being displayed

                newKdxx = app.Kdxx *0;        % flip the index 
                for ii=1:6
                    newKdxx(ii) = app.Kdxx(7-ii);
                end

                displayKdxx(app, newKdxx);      
            elseif strcmp('Rms(x)',selectedButton.Text) 
                app.graph2_select = 1;       % set flag to indicate what is being displayed
        

                newRmxx = app.Rmxx *0;      % flip the index 
                for ii=1:6
                    newRmxx(ii) = app.Rmxx(7-ii);
                end

                displayRmxx(app, newRmxx);  
            else
                app.graph2_select = 2;       % set flag to indicate what is being displayed

                newRmvv = app.Rmxx*0;
                newRmvv(1) = app.Rmxx(end);
                displayRmvv(app, newRmvv);  
            end
            
        end

        % Value changed function: CmsmNEditField
        function CmsmNEditFieldValueChanged(app, event)
            value = app.CmsmNEditField.Value;
            app.cmmNEditField.Value = value;
            cmmNEditFieldValueChanged(app,event);
        end

        % Value changed function: MmdkgEditField
        function MmdkgEditFieldValueChanged(app, event)
            value = app.MmdkgEditField.Value;   % not includeing air load
            app.mmkgEditField.Value = value;
            mmkgEditFieldValueChanged(app, event)
        end

        % Value changed function: RmskgsEditField
        function RmsEditFieldValueChanged(app, event)
            value = app.RmskgsEditField.Value;
            app.rmkgsEditField.Value = value;
            rmkgsEditFieldValueChanged(app, event);
        end

        % Value changed function: MmsEditField
        function MmsEditFieldValueChanged(app, event)
            value = app.MmsEditField.Value;
            % MMS = MMD + MAR
            mmd = value - app.Mar; 
            app.mmkgEditField.Value = mmd;
            mmkgEditFieldValueChanged(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ThieleSmallCalculatorUIFigure = uifigure('Visible', 'off');
            app.ThieleSmallCalculatorUIFigure.Name = 'ThieleSmall Calculator';
            app.ThieleSmallCalculatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @ThieleSmallCalculatorUIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.ElectricParametersPanel = uipanel(app.Panel_2);
            app.ElectricParametersPanel.Title = 'Electric Parameters';
            app.ReohmsEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.ReohmsEditFieldLabel.Text = 'Re (ohms)';
            app.ReohmsEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.ReohmsEditField.ValueChangedFcn = createCallbackFcn(app, @ReohmsEditFieldValueChanged, true);
            app.LeHEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.LeHEditFieldLabel.Text = 'Le (H)';
            app.LeHEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.LeHEditField.ValueChangedFcn = createCallbackFcn(app, @LeHEditFieldValueChanged, true);
            app.BlNAEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.BlNAEditFieldLabel.Text = 'Bl (N/A)';
            app.BlNAEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.BlNAEditField.ValueChangedFcn = createCallbackFcn(app, @BlNAEditFieldValueChanged, true);
            app.MechanicalParametersPanel = uipanel(app.Panel_2);
            app.MechanicalParametersPanel.Title = 'Mechanical Parameters';
            app.mmkgEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.mmkgEditFieldLabel.Text = 'mm (kg)';
            app.mmkgEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.mmkgEditField.ValueChangedFcn = createCallbackFcn(app, @mmkgEditFieldValueChanged, true);
            app.cmmNEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.cmmNEditFieldLabel.Text = 'cm (m/N)';
            app.cmmNEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.cmmNEditField.ValueChangedFcn = createCallbackFcn(app, @cmmNEditFieldValueChanged, true);
            app.rmkgsEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.rmkgsEditFieldLabel.Text = 'rm (kg/s)';
            app.rmkgsEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.rmkgsEditField.ValueChangedFcn = createCallbackFcn(app, @rmkgsEditFieldValueChanged, true);
            app.xmaxcmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.xmaxcmEditFieldLabel.Text = 'xmax (cm)';
            app.xmaxcmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.xmaxcmEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxcmEditFieldValueChanged, true);
            app.outrmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.outrmEditFieldLabel.Text = 'out r (m)';
            app.outrmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.outrmEditField.ValueChangedFcn = createCallbackFcn(app, @outrmEditFieldValueChanged, true);
            app.inrmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.inrmEditFieldLabel.Text = 'in r (m)';
            app.inrmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.inrmEditField.ValueChangedFcn = createCallbackFcn(app, @inrmEditFieldValueChanged, true);
            app.Panel = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.ThieleSmallParametersPanel_2 = uipanel(app.Panel);
            app.ThieleSmallParametersPanel_2.Title = 'Thiele-Small Parameters';
            app.ReEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.ReEditField.ValueChangedFcn = createCallbackFcn(app, @ReEditFieldValueChanged, true);
            app.QesEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.QesEditFieldLabel.Text = 'Qes';
            app.QesEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.QesEditField.ValueChangedFcn = createCallbackFcn(app, @QesEditFieldValueChanged, true);
            app.QmsEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.QmsEditFieldLabel.Text = 'Qms';
            app.QmsEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.QmsEditField.ValueChangedFcn = createCallbackFcn(app, @QmsEditFieldValueChanged, true);
            app.fsHzEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.fsHzEditFieldLabel.Text = 'fs (Hz)';
            app.fsHzEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.fsHzEditField.ValueChangedFcn = createCallbackFcn(app, @fsHzEditFieldValueChanged, true);
            app.Sdm2EditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.Sdm2EditFieldLabel.Text = 'Sd (m^2)';
            app.Sdm2EditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.Sdm2EditField.ValueChangedFcn = createCallbackFcn(app, @Sdm2EditFieldValueChanged, true);
            app.VasLEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.VasLEditFieldLabel.Text = 'Vas (L)';
            app.VasLEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.VasLEditField.ValueChangedFcn = createCallbackFcn(app, @VasLEditFieldValueChanged, true);
            app.MmsEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.MmsEditFieldLabel.Text = 'Mms';
            app.MmsEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.MmsEditField.ValueChangedFcn = createCallbackFcn(app, @MmsEditFieldValueChanged, true);
            app.MmdkgEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.MmdkgEditFieldLabel.Text = 'Mmd (kg)';
            app.MmdkgEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.MmdkgEditField.ValueChangedFcn = createCallbackFcn(app, @MmdkgEditFieldValueChanged, true);
            app.RmskgsEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.RmskgsEditFieldLabel.Text = 'Rms (kg/s)';
            app.RmskgsEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.RmskgsEditField.ValueChangedFcn = createCallbackFcn(app, @RmsEditFieldValueChanged, true);
            app.CmsmNEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.CmsmNEditFieldLabel.Text = 'Cms (m/N)';
            app.CmsmNEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.CmsmNEditField.ValueChangedFcn = createCallbackFcn(app, @CmsmNEditFieldValueChanged, true);
            app.Panel_3 = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.SpeakerPortsPanel = uipanel(app.Panel_3);
            app.SpeakerPortsPanel.Title = 'Speaker Ports';
            app.numportsEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.numportsEditFieldLabel.Text = 'num ports';
            app.numportsEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.numportsEditField.ValueChangedFcn = createCallbackFcn(app, @numportsEditFieldValueChanged, true);
            app.portradiusmEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.portradiusmEditFieldLabel.Text = 'port radius (m)';
            app.portradiusmEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.portradiusmEditField.ValueChangedFcn = createCallbackFcn(app, @portradiusmEditFieldValueChanged, true);
            app.portlengthmEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.portlengthmEditFieldLabel.Text = 'port length (m)';
            app.portlengthmEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.portlengthmEditField.ValueChangedFcn = createCallbackFcn(app, @portlengthmEditFieldValueChanged, true);
            app.SpeakerEnclosurePanel = uipanel(app.Panel_3);
            app.SpeakerEnclosurePanel.Title = 'Speaker Enclosure ';
            app.Volumeboxm3Label = uilabel(app.SpeakerEnclosurePanel);
            app.Volumeboxm3Label.Text = 'Volume box (m^3)';
            app.Volumeboxm3EditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.Volumeboxm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumeboxm3EditFieldValueChanged, true);
            app.backaream2Label = uilabel(app.SpeakerEnclosurePanel);
            app.backaream2Label.Text = 'back area (m^2)';
            app.backaream2EditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.backaream2EditField.ValueChangedFcn = createCallbackFcn(app, @backaream2EditFieldValueChanged, true);
            app.boxdepthmEditFieldLabel = uilabel(app.SpeakerEnclosurePanel);
            app.boxdepthmEditFieldLabel.Text = 'box depth (m)';
            app.boxdepthmEditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.boxdepthmEditField.ValueChangedFcn = createCallbackFcn(app, @boxdepthmEditFieldValueChanged, true);
            app.EnclosureInterpretedparamPanel = uipanel(app.Panel_3);
            app.EnclosureInterpretedparamPanel.Title = 'Enclosure Interpreted param';
            app.FcHzEditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.FcHzEditFieldLabel.Text = 'Fc (Hz)';
            app.FcHzEditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.FcHzEditField.ValueChangedFcn = createCallbackFcn(app, @FcHzEditFieldValueChanged, true);
            app.QtcEditField_2Label = uilabel(app.EnclosureInterpretedparamPanel);
            app.QtcEditField_2Label.Text = 'Qtc';
            app.QtcEditField_2 = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.QtcEditField_2.ValueChangedFcn = createCallbackFcn(app, @QtcEditField_2ValueChanged, true);
            app.EffVolumem3EditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.EffVolumem3EditFieldLabel.Text = 'Eff Volume (m^3)';
            app.EffVolumem3EditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.EffVolumem3EditField.ValueChangedFcn = createCallbackFcn(app, @EffVolumem3EditFieldValueChanged2, true);
            app.FirstmodefreqEditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.FirstmodefreqEditFieldLabel.Text = 'First mode freq';
            app.FirstmodefreqEditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.FirstmodefreqEditField.ValueChangedFcn = createCallbackFcn(app, @FirstmodefreqEditFieldValueChanged, true);
            app.powerPanel = uipanel(app.Panel_3);
            app.powerPanel.Title = 'power';
            app.estxmaxcmEditFieldLabel = uilabel(app.powerPanel);
            app.estxmaxcmEditFieldLabel.Text = 'est xmax (cm)';
            app.estxmaxcmEditField = uieditfield(app.powerPanel, 'numeric');
            app.InputpowerWEditFieldLabel = uilabel(app.powerPanel);
            app.InputpowerWEditFieldLabel.Text = 'Input power (W)';
            app.InputpowerWEditField = uieditfield(app.powerPanel, 'numeric');
            app.InputpowerWEditField.ValueChangedFcn = createCallbackFcn(app, @InputpowerWEditFieldValueChanged, true);
            app.AcousticmaterialPanel = uipanel(app.Panel_3);
            app.AcousticmaterialPanel.Title = 'Acoustic material';
            app.Volumematerialm3EditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.Volumematerialm3EditFieldLabel.Text = 'Volume material (m^3)';
            app.Volumematerialm3EditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.Volumematerialm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumematerialm3EditFieldValueChanged, true);
            app.materialRfraylmmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.materialRfraylmmEditFieldLabel.Text = 'material Rf (rayl/mm)';
            app.materialRfraylmmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.materialRfraylmmEditField.ValueChangedFcn = createCallbackFcn(app, @materialRfraylmmEditFieldValueChanged, true);
            app.VolratioVaVmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.VolratioVaVmEditFieldLabel.Text = 'Vol ratio (Va/Vm)';
            app.VolratioVaVmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.VolratioVaVmEditField.ValueChangedFcn = createCallbackFcn(app, @VolratioVaVmEditFieldValueChanged, true);
            app.Volumeairm3EditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.Volumeairm3EditFieldLabel.Text = 'Volume air (m^3)';
            app.Volumeairm3EditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.Volumeairm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumeairm3EditFieldValueChanged, true);
            app.materialticknessmmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.materialticknessmmEditFieldLabel.Text = 'material tickness (mm)';
            app.materialticknessmmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.materialticknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @materialticknessmmEditFieldValueChanged, true);
            app.HelpButton = uibutton(app.Panel_3, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.GeneralpropertiesPanel = uipanel(app.Panel_3);
            app.GeneralpropertiesPanel.Title = 'General properties';
            app.cmsEditFieldLabel = uilabel(app.GeneralpropertiesPanel);
            app.cmsEditFieldLabel.Text = 'c (m/s)';
            app.cmsEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.cmsEditField.ValueChangedFcn = createCallbackFcn(app, @cmsEditFieldValueChanged, true);
            app.gammaLabel = uilabel(app.GeneralpropertiesPanel);
            app.gammaLabel.Text = 'gamma ';
            app.gammaEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @gammaEditFieldValueChanged, true);
            app.PaLabel = uilabel(app.GeneralpropertiesPanel);
            app.PaLabel.Text = 'Pa ';
            app.PaEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.PaEditField.ValueChangedFcn = createCallbackFcn(app, @PaEditFieldValueChanged, true);
            app.rhokgm3EditFieldLabel = uilabel(app.GeneralpropertiesPanel);
            app.rhokgm3EditFieldLabel.Text = 'rho (kg/m^3)';
            app.rhokgm3EditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.rhokgm3EditField.ValueChangedFcn = createCallbackFcn(app, @rhokgm3EditFieldValueChanged, true);
            app.RecommendationPanel = uipanel(app.Panel_3);
            app.RecommendationPanel.Title = 'Recommendation';
            app.volumeairRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.volumeairRmdEditFieldLabel.Text = 'volume air Rmd';
            app.volumeairRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.VolboxRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.VolboxRmdEditFieldLabel.Text = 'Vol box Rmd';
            app.VolboxRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.volmatRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.volmatRmdEditFieldLabel.Text = 'vol mat Rmd';
            app.volmatRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.ImportButton = uibutton(app.Panel_3, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.SaveButton = uibutton(app.Panel_3, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel_3, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExWButton = uibutton(app.Panel_3, 'push');
            app.ExWButton.ButtonPushedFcn = createCallbackFcn(app, @ExWButtonPushed, true);
            app.ExWButton.Text = 'ExW';
            app.Panel_5 = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.TSinterpretedparamPanel = uipanel(app.Panel_5);
            app.TSinterpretedparamPanel.Title = 'TS interpreted param';
            app.pifs2Qtss1EditFieldLabel = uilabel(app.TSinterpretedparamPanel);
            app.pifs2Qtss1EditFieldLabel.Text = '2 pi fs/ 2Qts (s^-1)';
            app.pifs2Qtss1EditField = uieditfield(app.TSinterpretedparamPanel, 'numeric');
            app.pifs2Qtss1EditField.ValueChangedFcn = createCallbackFcn(app, @pifs2Qtss1EditFieldValueChanged, true);
            app.Decayrate92Label = uilabel(app.TSinterpretedparamPanel);
            app.Decayrate92Label.Text = 'Decay rate (>92)';
            app.QtsEditFieldLabel = uilabel(app.TSinterpretedparamPanel);
            app.QtsEditFieldLabel.Text = 'Qts';
            app.QtsEditField = uieditfield(app.TSinterpretedparamPanel, 'numeric');
            app.QtsEditField.ValueChangedFcn = createCallbackFcn(app, @QtsEditFieldValueChanged, true);
            app.Panel_6 = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_6);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.NonlinearityforRmsPanel = uipanel(app.Panel_6);
            app.NonlinearityforRmsPanel.Title = 'Nonlinearity for Rms';
            app.AdjustgraphButton_2 = uibutton(app.NonlinearityforRmsPanel, 'push');
            app.AdjustgraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjustgraphButton_2Pushed, true);
            app.AdjustgraphButton_2.Text = 'Adjust graph';
            app.coef_X2EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X2EditField_2Label.Text = 'coef_X2';
            app.coef_X2EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X2EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditField_2ValueChanged, true);
            app.coef_X2EditField_2.Editable = 'off';
            app.coef_X1EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X1EditField_2Label.Text = 'coef_X1';
            app.coef_X1EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X1EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditField_2ValueChanged, true);
            app.coef_X1EditField_2.Editable = 'off';
            app.coef_X0EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X0EditField_2Label.Text = 'coef_X0';
            app.coef_X0EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X0EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditField_2ValueChanged, true);
            app.coef_X0EditField_2.Editable = 'off';
            app.coef_X3EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X3EditField_2Label.Text = 'coef_X3';
            app.coef_X3EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X3EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditField_2ValueChanged, true);
            app.coef_X3EditField_2.Editable = 'off';
            app.coef_X4EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X4EditField_2Label.Text = 'coef_X4';
            app.coef_X4EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X4EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditField_2ValueChanged, true);
            app.coef_X4EditField_2.Editable = 'off';
            app.ReloadButton_2 = uibutton(app.NonlinearityforRmsPanel, 'push');
            app.ReloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReloadButton_2Pushed, true);
            app.ReloadButton_2.Text = 'Reload';
            app.coef_X5EditField_2Label = uilabel(app.NonlinearityforRmsPanel);
            app.coef_X5EditField_2Label.Text = 'coef_X5';
            app.coef_X5EditField_2 = uieditfield(app.NonlinearityforRmsPanel, 'numeric');
            app.coef_X5EditField_2.Editable = 'off';
            app.selectNonlinearityButtonGroup = uibuttongroup(app.Panel_6);
            app.selectNonlinearityButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @graph1_select_callback, true);
            app.selectNonlinearityButtonGroup.Title = 'select Nonlinearity';
            app.BlxButton = uiradiobutton(app.selectNonlinearityButtonGroup);
            app.BlxButton.Text = 'Bl(x)';
            app.BlxButton.Value = true;
            app.LxButton_3 = uiradiobutton(app.selectNonlinearityButtonGroup);
            app.LxButton_3.Text = 'L(x)';
            app.Panel_7 = uipanel(app.ThieleSmallCalculatorUIFigure);
            app.UIAxes_3 = uiaxes(app.Panel_7);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NonlinearityforKPanel = uipanel(app.Panel_7);
            app.NonlinearityforKPanel.Title = 'Nonlinearity for K';
            app.AdjustgraphButton_3 = uibutton(app.NonlinearityforKPanel, 'push');
            app.AdjustgraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdjustgraphButton_3Pushed, true);
            app.AdjustgraphButton_3.Text = 'Adjust graph';
            app.coef_X2EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X2EditField_3Label.Text = 'coef_X2';
            app.coef_X2EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X2EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditField_3ValueChanged, true);
            app.coef_X2EditField_3.Editable = 'off';
            app.coef_X1EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X1EditField_3Label.Text = 'coef_X1';
            app.coef_X1EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X1EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditField_3ValueChanged, true);
            app.coef_X1EditField_3.Editable = 'off';
            app.coef_X0EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X0EditField_3Label.Text = 'coef_X0';
            app.coef_X0EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X0EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditField_3ValueChanged, true);
            app.coef_X0EditField_3.Editable = 'off';
            app.coef_X3EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X3EditField_3Label.Text = 'coef_X3';
            app.coef_X3EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X3EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditField_3ValueChanged, true);
            app.coef_X3EditField_3.Editable = 'off';
            app.coef_X4EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X4EditField_3Label.Text = 'coef_X4';
            app.coef_X4EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X4EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditField_3ValueChanged, true);
            app.coef_X4EditField_3.Editable = 'off';
            app.ReloadButton_3 = uibutton(app.NonlinearityforKPanel, 'push');
            app.ReloadButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReloadButton_3Pushed, true);
            app.ReloadButton_3.Text = 'Reload';
            app.coef_X5EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X5EditField_3Label.Text = 'coef_X5';
            app.coef_X5EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X5EditField_3.Editable = 'off';
            app.selectNonlinearityButtonGroup_2 = uibuttongroup(app.Panel_7);
            app.selectNonlinearityButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @graph2_select_callback, true);
            app.selectNonlinearityButtonGroup_2.Title = 'select Nonlinearity';
            app.KxButton = uiradiobutton(app.selectNonlinearityButtonGroup_2);
            app.KxButton.Text = 'K(x)';
            app.KxButton.Value = true;
            app.RmsxButton = uiradiobutton(app.selectNonlinearityButtonGroup_2);
            app.RmsxButton.Text = 'Rms(x)';
            app.RmsvButton = uiradiobutton(app.selectNonlinearityButtonGroup_2);
            app.RmsvButton.Text = 'Rms(v)';
            % Show the figure after all components are created
            app.ThieleSmallCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thieleSmallCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ThieleSmallCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ThieleSmallCalculatorUIFigure)
        end
    end
end